classdef pspm_appdesigner < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    layout                       matlab.ui.Figure
    logo                         matlab.ui.control.Button
    attribution                  matlab.ui.control.Label
    button_open_issue            matlab.ui.control.Button
    button_help                  matlab.ui.control.Button
    button_report_second_level   matlab.ui.control.Button
    text_more                    matlab.ui.control.Label
    button_data_preparation      matlab.ui.control.DropDown
    button_data_preprocessing    matlab.ui.control.DropDown
    text_data_preprocessing      matlab.ui.control.Label
    button_first_level_models    matlab.ui.control.DropDown
    button_batch                 matlab.ui.control.Button
    button_tools                 matlab.ui.control.DropDown
    text_tools                   matlab.ui.control.Label
    button_models_for_sf         matlab.ui.control.Button
    button_non_linear_scr_model  matlab.ui.control.Button
    text_second_level_model      matlab.ui.control.Label
    text_first_level_models      matlab.ui.control.Label
    text_data_preparation        matlab.ui.control.Label
    button_export_statistics     matlab.ui.control.Button
    button_contrast_manager      matlab.ui.control.Button
    button_review_model          matlab.ui.control.Button
    button_second_level_model    matlab.ui.control.Button
    button_quit                  matlab.ui.control.Button
  end

    methods (Access = private)
        function Import_data_Callback(~)
            % pspm_import_UI;
            cfg_add_module('pspm.prep.import');
        end
        function Trim_data_Callback(~)
            % pspm_trim_UI;
            cfg_add_module('pspm.prep.trim');
        end
        function dispdata_Callback(~)
            % pspm_disp;
            cfg_add_module('pspm.tools.disp');
        end
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            % Load data for component configuration
            componentData = load('pspm_appdesigner.mat');
            app = pspm_ui_app(app);
            % Set component properties that require runtime configuration
            app.button_tools.UserData = componentData.Other_utils.UserData;
            app.button_data_preprocessing.UserData = componentData.ppDataPreprocessing.UserData;
            app.button_data_preparation.UserData = componentData.ppDataPreparation.UserData;
        end
    end

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function PsPM_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app);
            pspm_init; % required for cfg_util('initcfg');
            cfg_util('initcfg'); % This must be the first call to cfg_util
            %cfg_ui('Visible','off'); % Create invisible batch ui
            %handles.output = hObject; % Choose default command line output for PsPM
            %guidata(hObject, handles); % Update handles structure
    end

    % Button pushed function: button_non_linear_scr_model
    function button_non_linear_scr_model_Callback(app, event)
            % pspm_dcm_UI;
            cfg_add_module('pspm.first_level.scr.dcm');
    end

    % Button pushed function: button_export_statistics
    function button_export_statistics_Callback(app, event)
            % pspm_exp_UI;
            cfg_add_module('pspm.first_level.export');
    end

    % Value changed function: button_first_level_models
    function button_first_level_models_Callback(app, event)
           switch event.Value
                case 'GLM for SCR'
                    cfg_add_module('pspm.first_level.scr.glm_scr');
                case 'GLM for HPR (evoked)'
                    cfg_add_module('pspm.first_level.hp.glm_hp_e');
                case 'GLM for HPR (fear-conditioning)'
                    cfg_add_module('pspm.first_level.hp.glm_hp_fc');
                case 'GLM for PSR (fear-conditioning)'
                    cfg_add_module('pspm.first_level.ps.glm_ps_fc');
                case 'GLM for RAR (evoked)'
                    cfg_add_module('pspm.first_level.resp.glm_ra_e');
                case 'GLM for RAR (fear-conditioning)'
                    cfg_add_module('pspm.first_level.resp.glm_ra_fc');
                case 'GLM for RPR (evoked)'
                    cfg_add_module('pspm.first_level.resp.glm_rp_e');
                case 'GLM for RFRR (evoked)'
                    cfg_add_module('pspm.first_level.resp.glm_rfr_e');
                case 'GLM for SEBR'
                    cfg_add_module('pspm.first_level.sebr.glm_sebr');
                case 'GLM for SPS'
                    cfg_add_module('pspm.first_level.sps.glm_sps');
            end
    end

    % Value changed function: button_tools
    function button_tools_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to Other_utils (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = get(hObject,'String') returns Other_utils contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from Other_utils
            val = get(hObject,'Value');
            switch val
                case 1
                    pspm_display;
                case 2
                    cfg_add_module('pspm.tools.rename');
                case 3
                    cfg_add_module('pspm.tools.split_sessions');
                case 4
                    cfg_add_module('pspm.tools.merge');
                case 5
                    cfg_add_module('pspm.tools.artefact_rm');
                case 6
                    cfg_add_module('pspm.tools.downsample');
                case 7
                    cfg_add_module('pspm.tools.interpolate');
                case 8
                    cfg_add_module('pspm.tools.extract_segments');
                case 9
                    cfg_add_module('pspm.tools.segment_mean');
                case 10
                    cfg_add_module('pspm.tools.extract_markerinfo');
                case 11
                    pspm_data_editor();
                case 12
                    cfg_add_module('pspm.tools.convert_data');
            end
    end

    % Button pushed function: button_open_issue
    function PsPM_ButtonDownFcnForum(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to PsPM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_show_forum;
    end

    % Button pushed function: button_help
    function PsPM_ButtonDownFcnHelp(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to PsPM (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_show_help_doc;
    end

    % Button pushed function: button_quit
    function button_quit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to QuitGUI (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            pspm_quit; return
    end

    % Button pushed function: button_models_for_sf
    function button_models_for_sf_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to SF (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_sf_UI;
            cfg_add_module('pspm.first_level.scr.sf');
    end

    % Button pushed function: button_batch
    function button_batch_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to batch_pushbutton (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cfg_ui;
    end

    % Button pushed function: button_contrast_manager
    function button_contrast_manager_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to con1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_con1_UI;
            pspm_contrast;
    end

    % Button pushed function: button_second_level_model
    function button_second_level_model_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to con2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % pspm_con2_UI;
            cfg_add_module('pspm.second_level.contrast');
    end

    % Value changed function: button_data_preparation
    function button_data_preparation_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ppDataPreparation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns ppDataPreparation contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ppDataPreparation

            selected = get(hObject,'Value');

            switch selected
                case 1
                    cfg_add_module('pspm.prep.import');
                case 2
                    cfg_add_module('pspm.prep.trim');
            end
    end

    % Value changed function: button_data_preprocessing
    function button_data_preprocessing_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to ppDataPreprocessing (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

            % Hints: contents = cellstr(get(hObject,'String')) returns ppDataPreprocessing contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from ppDataPreprocessing

            selected = get(hObject,'Value');

            switch selected
                case 1
                    cfg_add_module('pspm.data_preprocessing.pp_heart_period.pp_heart_data');
                case 2
                    %cfg_add_module('pspm.data_preprocessing.pp_heart_period.ecg_editor');
                    pspm_ecg_editor();
                case 3
                    cfg_add_module('pspm.data_preprocessing.resp_pp');
                case 4
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.process_illuminance');
                case 5
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.find_valid_fixations');
                case 6
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_correct');
                case 7
                    cfg_add_module('pspm.data_preprocessing.pp_pupil.pupil_preprocess');
                case 8
                    cfg_add_module('pspm.data_preprocessing.pupil_size_convert');
                case 9
                    cfg_add_module('pspm.data_preprocessing.gaze_convert');
                    %case 10
                    %    cfg_add_module('pspm.tools.convert_data');
                case 10
                    cfg_add_module('pspm.data_preprocessing.pp_emg.find_sounds');
                case 11
                    cfg_add_module('pspm.data_preprocessing.pp_emg.pp_emg_data');
                case 12
                    cfg_add_module('pspm.data_preprocessing.pp_scr');
            end
    end

    % Button pushed function: button_review_model
    function button_review_model_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rev1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %pspm_rev1_UI;
            pspm_review;
    end

    % Button pushed function: button_report_second_level
    function button_report_second_level_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>

            % hObject    handle to rev2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            cfg_add_module('pspm.second_level.report');
    end

    % Callback function
    function PsPMButtonPushed(app, event)
            pspm_show_arms;
    end

    % Button pushed function: logo
    function logoPushed(app, event)
            pspm_show_arms;
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.layout = uifigure('Visible', 'off');
      colormap(app.layout, 'parula');
      app.layout.Name = 'pspm';
      app.layout.Tag = 'figure1';
      app.button_quit = uibutton(app.layout, 'push');
      app.button_quit.ButtonPushedFcn = createCallbackFcn(app, @button_quit_Callback, true);
      app.button_quit.Tag = 'QuitGUI';
      app.button_quit.Tooltip = 'Goodbye.';
      app.button_quit.Text = 'Quit';
      app.button_second_level_model = uibutton(app.layout, 'push');
      app.button_second_level_model.ButtonPushedFcn = createCallbackFcn(app, @button_second_level_model_Callback, true);
      app.button_second_level_model.Tag = 'con2';
      app.button_second_level_model.Tooltip = 'Define group level model';
      app.button_second_level_model.Text = 'Second Level Model';
      app.button_review_model = uibutton(app.layout, 'push');
      app.button_review_model.ButtonPushedFcn = createCallbackFcn(app, @button_review_model_Callback, true);
      app.button_review_model.Tag = 'rev1';
      app.button_review_model.Tooltip = 'Review a first level model';
      app.button_review_model.Text = 'Review Model';
      app.button_contrast_manager = uibutton(app.layout, 'push');
      app.button_contrast_manager.ButtonPushedFcn = createCallbackFcn(app, @button_contrast_manager_Callback, true);
      app.button_contrast_manager.Tag = 'con1';
      app.button_contrast_manager.Tooltip = 'First level contrasts for various analysis methods';
      app.button_contrast_manager.Text = 'Contrast Manager';
      app.button_export_statistics = uibutton(app.layout, 'push');
      app.button_export_statistics.ButtonPushedFcn = createCallbackFcn(app, @button_export_statistics_Callback, true);
      app.button_export_statistics.Tag = 'Export_data';
      app.button_export_statistics.Tooltip = 'Export your data for group level analysis outside PsPM/SCRalyze';
      app.button_export_statistics.Text = 'Export Statistics';
      app.text_data_preparation = uilabel(app.layout);
      app.text_data_preparation.Tag = 'Import_PP_header';
      app.text_data_preparation.Text = 'Data Preparation';
      app.text_first_level_models = uilabel(app.layout);
      app.text_first_level_models.Tag = 'Level1_text';
      app.text_first_level_models.Text = 'First Level Models';
      app.text_second_level_model = uilabel(app.layout);
      app.text_second_level_model.Tag = 'Level_2_text';
      app.text_second_level_model.Text = 'Second Level';
      app.button_non_linear_scr_model = uibutton(app.layout, 'push');
      app.button_non_linear_scr_model.ButtonPushedFcn = createCallbackFcn(app, @button_non_linear_scr_model_Callback, true);
      app.button_non_linear_scr_model.Tag = 'DCM';
      app.button_non_linear_scr_model.Tooltip = 'Dynamic causal modelling';
      app.button_non_linear_scr_model.Text = 'Non-linear SCR model';
      app.button_models_for_sf = uibutton(app.layout, 'push');
      app.button_models_for_sf.ButtonPushedFcn = createCallbackFcn(app, @button_models_for_sf_Callback, true);
      app.button_models_for_sf.Tag = 'SF';
      app.button_models_for_sf.Tooltip = 'Spontaneous fluctuations and skin conductance level';
      app.button_models_for_sf.Text = 'Models for SF';
      app.text_tools = uilabel(app.layout);
      app.text_tools.Tag = 'Other_utils_text';
      app.text_tools.Text = 'Tools';
      app.button_tools = uidropdown(app.layout);
      app.button_tools.Items = {'Click to select', 'Display data', 'Rename file', 'Split sessions', 'Merge files', 'Artefact removal', 'Downsample data', 'Interpolate missing data', 'Extract segments', 'Segment mean', 'Extract event marker info', 'Data editor', 'Data convert'};
      app.button_tools.ValueChangedFcn = createCallbackFcn(app, @button_tools_Callback, true);
      app.button_tools.Tag = 'Other_utils';
      app.button_tools.Tooltip = 'Various utilities';
      app.button_tools.Value = 'Click to select';
      app.button_batch = uibutton(app.layout, 'push');
      app.button_batch.ButtonPushedFcn = createCallbackFcn(app, @button_batch_Callback, true);
      app.button_batch.Tag = 'batch_pushbutton';
      app.button_batch.Tooltip = 'Batch Editor';
      app.button_batch.Text = 'Batch';
      app.button_first_level_models = uidropdown(app.layout);
      app.button_first_level_models.Items = {'Click to select', 'GLM for SCR', 'GLM for HPR (evoked)', 'GLM for HPR (fear-conditioning)', 'GLM for PSR (fear-conditioning)', 'GLM for RAR (evoked)', 'GLM for RAR (fear-conditioning)', 'GLM for RPR (evoked)', 'GLM for RFRR (evoked)', 'GLM for SEBR', 'GLM for SPS'};
      app.button_first_level_models.ValueChangedFcn = createCallbackFcn(app, @button_first_level_models_Callback, true);
      app.button_first_level_models.Tag = 'GLM';
      app.button_first_level_models.Value = 'Click to select';
      app.text_data_preprocessing = uilabel(app.layout);
      app.text_data_preprocessing.Tag = 'txtDataPreproc';
      app.text_data_preprocessing.Text = 'Data Preprocessing';
      app.button_data_preprocessing = uidropdown(app.layout);
      app.button_data_preprocessing.Items = {'Click to select', 'Preprocess heart data', 'ECG editor', 'Preprocess respiration data', 'Prepare illuminance GLM', 'Find valid fixations', 'Pupil foreshortening error correction', 'Pupil preprocessing', 'Pupil size convert', 'Gaze convert', 'Find startle sound onsets', 'Preprocess startle eyeblink EMG', 'Preprocessing SCR'};
      app.button_data_preprocessing.ValueChangedFcn = createCallbackFcn(app, @button_data_preprocessing_Callback, true);
      app.button_data_preprocessing.Tag = 'ppDataPreprocessing';
      app.button_data_preprocessing.Tooltip = 'Data preprocessing';
      app.button_data_preprocessing.Value = 'Click to select';
      app.button_data_preparation = uidropdown(app.layout);
      app.button_data_preparation.Items = {'Click to select', 'Import', 'Trim'};
      app.button_data_preparation.ValueChangedFcn = createCallbackFcn(app, @button_data_preparation_Callback, true);
      app.button_data_preparation.Tag = 'ppDataPreparation';
      app.button_data_preparation.Tooltip = 'Data preparation';
      app.button_data_preparation.Value = 'Click to select';
      app.text_more = uilabel(app.layout);
      app.text_more.Tag = 'txtMore';
      app.text_more.Text = 'More';
      app.button_report_second_level = uibutton(app.layout, 'push');
      app.button_report_second_level.ButtonPushedFcn = createCallbackFcn(app, @button_report_second_level_Callback, true);
      app.button_report_second_level.Tag = 'rev2';
      app.button_report_second_level.Tooltip = 'Report 2nd level model';
      app.button_report_second_level.Text = 'Report Second Level';
      app.button_help = uibutton(app.layout, 'push');
      app.button_help.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnHelp, true);
      app.button_help.Tag = 'pushbutton24';
      app.button_help.Tooltip = 'Help!';
      app.button_help.Text = 'Help';
      app.button_open_issue = uibutton(app.layout, 'push');
      app.button_open_issue.ButtonPushedFcn = createCallbackFcn(app, @PsPM_ButtonDownFcnForum, true);
      app.button_open_issue.Tag = 'pushbutton25';
      app.button_open_issue.Tooltip = 'Help!';
      app.button_open_issue.Text = 'Open Issue';
      app.attribution = uilabel(app.layout);
      app.attribution.Text = {'Version 6.1'; 'Build 13092022 with MATLAB 2022a, The PsPM Team, University College London'};
      app.logo = uibutton(app.layout, 'push');
      app.logo.ButtonPushedFcn = createCallbackFcn(app, @logoPushed, true);
      app.logo.Text = 'PsPM';
      % Show the figure after all components are created
      app.layout.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = pspm_appdesigner(varargin)

      runningApp = getRunningApp(app);

      % Check for running singleton app
      if isempty(runningApp)

        % Create UIFigure and components
        createComponents(app)

        % Register the app with App Designer
        registerApp(app, app.layout)

        % Execute the startup function
        runStartupFcn(app, @(app)PsPM_OpeningFcn(app, varargin{:}))
      else

        % Focus the running singleton app
        figure(runningApp.layout)

        app = runningApp;
      end

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.layout)
    end
  end
end